classdef Moduselemzo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ParametersPanel                 matlab.ui.container.Panel
        FeedbackSwitchLabel             matlab.ui.control.Label
        FeedbackSwitch                  matlab.ui.control.Switch
        StartButton                     matlab.ui.control.Button
        MasskgEditFieldLabel            matlab.ui.control.Label
        MasskgEditField                 matlab.ui.control.NumericEditField
        DampingcoeffEditFieldLabel      matlab.ui.control.Label
        DampingcoeffEditField           matlab.ui.control.NumericEditField
        SpringstiffnessLabel            matlab.ui.control.Label
        SpringstiffnessEditField        matlab.ui.control.NumericEditField
        ExcitationforceNEditFieldLabel  matlab.ui.control.Label
        ExcitationforceNEditField       matlab.ui.control.NumericEditField
        NumberofmassesEditFieldLabel    matlab.ui.control.Label
        NumberofmassesEditField         matlab.ui.control.NumericEditField
        StartomegaLabel                 matlab.ui.control.Label
        StartomegaEditField             matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel        matlab.ui.control.Label
        ResolutionEditField             matlab.ui.control.NumericEditField
        OmegamultiplierLabel            matlab.ui.control.Label
        OmegamultiplierEditField        matlab.ui.control.NumericEditField
        PlacementofexcitationforceEditFieldLabel  matlab.ui.control.Label
        PlacementofexcitationforceEditField  matlab.ui.control.NumericEditField
        ExportButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ModesTab                        matlab.ui.container.Tab
        NextButton_3                    matlab.ui.control.Button
        PrevButton_3                    matlab.ui.control.Button
        FirstmodeEditFieldLabel         matlab.ui.control.Label
        FirstmodeEditField              matlab.ui.control.NumericEditField
        LastmodeEditFieldLabel          matlab.ui.control.Label
        LastmodeEditField               matlab.ui.control.NumericEditField
        PlotmodesLabel                  matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        DisplacementsTab                matlab.ui.container.Tab
        NextButton_2                    matlab.ui.control.Button
        PrevButton_2                    matlab.ui.control.Button
        FirstmassEditFieldLabel         matlab.ui.control.Label
        FirstmassEditField              matlab.ui.control.NumericEditField
        LastmassEditFieldLabel          matlab.ui.control.Label
        LastmassEditField               matlab.ui.control.NumericEditField
        PlotFRFLabel                    matlab.ui.control.Label
        PlotButton_2                    matlab.ui.control.Button
        ClearButton_2                   matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property2 % Description
        pltnumber = 1;
        pltnumber2 = 1;
        N = 1;
        m = 0.02;
        c = 100;
        k = 300000;
        f = 1;
        Ms = 18;
        M;
        C;
        K;
        U;
        logabstU;
        ALFA;
        omega;
        FI;
        OMEGA2;
        OMEGA;
        fnum = 10;
        omegakezdo = 0.00001;
        Nomega = 5000; 
        Kiertekeles = 1.5;
        visszacsatolt = 1;
        
        abstU;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             uieditfield(app.FirstmodeEditField,'Limits', [1 inf])
            app.FirstmodeEditField.Limits = [1 inf];
            app.LastmodeEditField.Limits = [1 inf];
            app.FirstmassEditField.Limits = [1 inf];
            app.LastmassEditField.Limits = [1 inf];
            
            app.MasskgEditField.Limits = [0 inf];
            app.MasskgEditField.Value = app.m;
            
            app.DampingcoeffEditField.Limits = [0 inf];
            app.DampingcoeffEditField.Value = app.c;
            
            app.SpringstiffnessEditField.Limits = [0 inf];
            app.SpringstiffnessEditField.Value = app.k;
            
            app.ExcitationforceNEditField.Limits = [0 inf];
            app.ExcitationforceNEditField.Value = app.f;
            
            app.NumberofmassesEditField.Limits = [1 inf];
            app.NumberofmassesEditField.Value = app.Ms;
            
            app.StartomegaEditField.Limits = [0 inf];
            app.StartomegaEditField.Value = app.omegakezdo;
            
            app.OmegamultiplierEditField.Limits = [0.01 inf];
            app.OmegamultiplierEditField.Value = app.Kiertekeles;
            
            app.ResolutionEditField.Limits = [100 inf];
            app.ResolutionEditField.Value = app.Nomega;
            
            app.PlacementofexcitationforceEditField.Limits = [1 inf];
            app.PlacementofexcitationforceEditField.Value = app.fnum; 
            
            set(app.FeedbackSwitch, 'Value', 'On');
            app.LastmassEditField.Value = app.Ms;
            app.LastmodeEditField.Value = app.Ms;
            
        end

        % Callback function
        function TmegEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function CsillaptsitnyezEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function RugmerevsgEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function GerjeszterkEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function TmegpontokszmaEditFieldValueChanged(app, event)
         
        end

        % Value changed function: FeedbackSwitch
        function FeedbackSwitchValueChanged(app, event)
            app.FeedbackSwitch.Items = {'Off' 'On'};
            value = app.FeedbackSwitch.Value;
            if value == "On"
                app.visszacsatolt = str2double(1);
            else
                app.visszacsatolt = str2double(0);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            tm = app.MasskgEditField.Value;
            tc = app.DampingcoeffEditField.Value;           
            tk = app.SpringstiffnessEditField.Value;
            tf = app.ExcitationforceNEditField.Value;
            tMs = app.NumberofmassesEditField.Value;
            
            tempVisszacsatolt = app.FeedbackSwitch.Value;
            tfnum = app.PlacementofexcitationforceEditField.Value;
            tomegakezdo = app.StartomegaEditField.Value;
            tNomega = app.ResolutionEditField.Value;
            tKiertekeles = app.OmegamultiplierEditField.Value;
            
            app.m = tm;
            app.c = tc;           
            app.k = tk;
            app.f = tf;
            app.Ms = tMs;            
            app.N = tMs;
            app.fnum = tfnum;
            app.omegakezdo = tomegakezdo;
            app.Nomega = tNomega;
            app.Kiertekeles = tKiertekeles;
            
            if tempVisszacsatolt == "On"
                app.visszacsatolt = 1;
                tvisszacsatolt = 1;                
            else
                app.visszacsatolt = 0;
                tvisszacsatolt = 0;                
            end
            
            [tM, tK, tC, tFI, tOMEGA2] = modusmatrixgenerator(tm, tk, tc, tMs, tvisszacsatolt);
            %[app.M, app.K, app.C, app.FI, app.OMEGA2] = modusmatrixgenerator(app.m, app.k, app.c, app.Ms, app.visszacsatolt);
            app.M = tM;
            app.K = tK;
            app.C = tC;
            app.FI = tFI;
            app.OMEGA2 = tOMEGA2;
            
            [tU,tALFA, tomega, tOMEGA] = elmozdulasszamitas(tC, tFI, tOMEGA2, tf, tfnum, tomegakezdo, tNomega, tKiertekeles, tMs);
            %[app.U, app.ALFA, app.omega, app.OMEGA] = elmozdulasszamitas(app.C, app.FI, app.OMEGA2, app.f, app.fnum, app.omegakezdo, app.Nomega, app.Kiertekeles, app.Ms);
            app.U = tU;
            app.ALFA = tALFA;
            app.omega = tomega;
            app.OMEGA = tOMEGA;
%             app.UIAxes.Title.String = strcat("Modus: ",num2str(app.pltnumber2));            
            plot(app.UIAxes,tFI(:,app.pltnumber))
            ylim = max(max(tFI))*1.5;
            app.UIAxes.YLim = [-1*ylim 1*ylim];
            tlogabstU = 20*log10(abs(tU));
            tabstU = 10^20*abs(tU);            
            app.abstU = tabstU;
            app.logabstU = tlogabstU;
%             app.UIAxes_2.Title.String = strcat("Elmozdulás: ",num2str(app.pltnumber2));
            plot(app.UIAxes_2, tomega, 20*log10(tU(app.pltnumber2,:)))
%             app.UIAxes_2.YScale = 'log';
%             xlim_2 = max(max(tOMEGA))*1.5;
            
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            app.pltnumber = app.pltnumber + 1;
            if app.pltnumber == app.N+1
                app.pltnumber = 1;
            end
%             app.UIAxes_2.Title.String = strcat("Modus: ",num2str(app.pltnumber));
            title(app.UIAxes, sprintf('FI Eigenvector %d',app.pltnumber))
            plot(app.UIAxes,app.FI(:,app.pltnumber));
        end

        % Button pushed function: PrevButton_3
        function PrevButton_3Pushed(app, event)
            app.pltnumber = app.pltnumber - 1;
                if app.pltnumber == 0
                    app.pltnumber = app.N;
                end
%             app.UIAxes_2.Title.String = strcat("Modus: ",num2str(app.pltnumber)); 
            title(app.UIAxes, sprintf('FI Eigenvector %d',app.pltnumber))
            plot(app.UIAxes,app.FI(:,app.pltnumber));
        end

        % Value changed function: MasskgEditField
        function MasskgEditFieldValueChanged(app, event)
%             value = app.MasskgEditField.Value;
%             app.m = str2double(value);
        end

        % Value changed function: DampingcoeffEditField
        function DampingcoeffEditFieldValueChanged(app, event)
%             value = app.DampingcoeffEditField.Value;
%             app.c = str2double(value);
        end

        % Value changed function: SpringstiffnessEditField
        function SpringstiffnessEditFieldValueChanged(app, event)
%             value = app.SpringstiffnessEditField.Value;
%             app.k = str2double(value);
        end

        % Value changed function: ExcitationforceNEditField
        function ExcitationforceNEditFieldValueChanged(app, event)
%             value = app.ExcitationforceNEditField.Value;
%             app.f = str2double(value);
        end

        % Value changed function: NumberofmassesEditField
        function NumberofmassesEditFieldValueChanged(app, event)
%             value = app.NumberofmassesEditField.Value;
%             app.Ms = str2double(value);
        end

        % Value changed function: StartomegaEditField
        function StartomegaEditFieldValueChanged(app, event)
%             value = app.StartomegaEditField.Value;
            
        end

        % Button pushed function: PrevButton_2
        function PrevButton_2Pushed(app, event)
            app.pltnumber2 = app.pltnumber2 - 1;
                if app.pltnumber2 == 0
                    app.pltnumber2 = app.N;
                end
              
%             app.UIAxes_2.Title.String = strcat("Elmozdulás: ",num2str(app.pltnumber2));
%             plot(app.UIAxes_2,app.logabstU(app.pltnumber2,:));
            title(app.UIAxes_2, sprintf('Displacement %d',app.pltnumber2));
            plot(app.UIAxes_2, app.omega, 20*log10(app.U(app.pltnumber2,:)));
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            app.pltnumber2 = app.pltnumber2 + 1;
            if app.pltnumber2 == app.N+1
                app.pltnumber2 = 1;
            end
%           app.UIAxes_2.Title.String = strcat("Elmozdulás: ",num2str(app.pltnumber2));
            %plot(app.UIAxes_2,app.logabstU(app.pltnumber2,:));
            
            title(app.UIAxes_2, sprintf('Displacement %d',app.pltnumber2))
            plot(app.UIAxes_2, app.omega, 20*log10(app.U(app.pltnumber2,:)))
            xlabel(app.UIAxes_2, 'xlabel')
            ylabel(app.UIAxes_2, 'ylabel');
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            cla(app.UIAxes)
            tstart = app.FirstmodeEditField.Value;
            tend = app.LastmodeEditField.Value;
            title(app.UIAxes, sprintf('FI Eigenvectors %d - %d', tstart, tend))           
            hold(app.UIAxes,'on')
            i = tstart;
            while i <= tend
                plot(app.UIAxes,app.FI(:, i))
                i = i+1;
                if i > app.N
                    i = 1;
                    tend = tend-app.N;
                end
            end            
            if tend == 0
                tend = app.N;
            end
            hold(app.UIAxes,'off')
            legends = tstart:tend;
            legend(app.UIAxes,num2str(legends'))     
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            cla(app.UIAxes_2);           
            tstart = app.FirstmassEditField.Value;
            startlegend = tstart;
            tend = app.LastmassEditField.Value;
            endlegend = tend;
            title(app.UIAxes_2, sprintf('Displacement %d - %d', tstart, tend))           
            hold(app.UIAxes_2,'on')
            i = tstart;
            while i <= tend
                plot(app.UIAxes_2,app.omega, 20*log10(app.U(i ,:)))
                i = i+1;                
                if i > app.N
                    i = 1;
                    tend = tend-app.N;
                end
            end
            if tend == 0
                tend = app.N;
            end            
            if endlegend-startlegend >= 20
                endlegend = startlegend + 19;
            end
            legends = startlegend:endlegend;            
            hold(app.UIAxes_2,'off')
            legend(app.UIAxes_2,num2str(legends'))
            legend(app.UIAxes_2,num2str(legends'))
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes)
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            cla(app.UIAxes_2)
        end

        % Value changed function: FirstmassEditField
        function FirstmassEditFieldValueChanged(app, event)
%             value = app.FirstmassEditField.Value;
%             tN = app.NumberofmassesEditField;
%             uieditfield(app.FirstmodeEditField,'Limits', [1 tN])
        end

        % Value changed function: FirstmodeEditField
        function FirstmodeEditFieldValueChanged(app, event)
%             value = app.FirstmodeEditField.Value;
%             tN = app.NumberofmassesEditField;
%             uieditfield(app.FirstmassEditField,'Limits', [1 tN])
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            filename_disp = 'distplacements.mat';
            tU = app.U;
            tFI = app.FI;
            filename_modes = 'modes_FI.mat';         
            save(filename_disp,'tU')
            save(filename_modes, 'tFI')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.FeedbackSwitchLabel = uilabel(app.ParametersPanel);
            app.FeedbackSwitchLabel.Text = 'Feedback';
            app.FeedbackSwitch = uiswitch(app.ParametersPanel, 'slider');
            app.FeedbackSwitch.ValueChangedFcn = createCallbackFcn(app, @FeedbackSwitchValueChanged, true);
            app.StartButton = uibutton(app.ParametersPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.MasskgEditFieldLabel = uilabel(app.ParametersPanel);
            app.MasskgEditFieldLabel.Text = 'Mass [kg]:';
            app.MasskgEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.MasskgEditField.ValueChangedFcn = createCallbackFcn(app, @MasskgEditFieldValueChanged, true);
            app.DampingcoeffEditFieldLabel = uilabel(app.ParametersPanel);
            app.DampingcoeffEditFieldLabel.Text = 'Damping coeff. [-]:';
            app.DampingcoeffEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DampingcoeffEditField.ValueChangedFcn = createCallbackFcn(app, @DampingcoeffEditFieldValueChanged, true);
            app.SpringstiffnessLabel = uilabel(app.ParametersPanel);
            app.SpringstiffnessLabel.Text = 'Spring stiffness [-]:';
            app.SpringstiffnessEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SpringstiffnessEditField.ValueChangedFcn = createCallbackFcn(app, @SpringstiffnessEditFieldValueChanged, true);
            app.ExcitationforceNEditFieldLabel = uilabel(app.ParametersPanel);
            app.ExcitationforceNEditFieldLabel.Text = 'Excitation force [N]:';
            app.ExcitationforceNEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ExcitationforceNEditField.ValueChangedFcn = createCallbackFcn(app, @ExcitationforceNEditFieldValueChanged, true);
            app.NumberofmassesEditFieldLabel = uilabel(app.ParametersPanel);
            app.NumberofmassesEditFieldLabel.Text = 'Number of masses [-]:';
            app.NumberofmassesEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.NumberofmassesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofmassesEditFieldValueChanged, true);
            app.StartomegaLabel = uilabel(app.ParametersPanel);
            app.StartomegaLabel.Text = 'Start omega [-]:';
            app.StartomegaEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.StartomegaEditField.ValueChangedFcn = createCallbackFcn(app, @StartomegaEditFieldValueChanged, true);
            app.ResolutionEditFieldLabel = uilabel(app.ParametersPanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution [-]:';
            app.ResolutionEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.OmegamultiplierLabel = uilabel(app.ParametersPanel);
            app.OmegamultiplierLabel.Text = 'Omega multiplier [-]:';
            app.OmegamultiplierEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.PlacementofexcitationforceEditFieldLabel = uilabel(app.ParametersPanel);
            app.PlacementofexcitationforceEditFieldLabel.Text = {'Placement of '; 'excitation force [-]:'};
            app.PlacementofexcitationforceEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ExportButton = uibutton(app.ParametersPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ModesTab = uitab(app.TabGroup);
            app.ModesTab.Title = 'Modes';
            app.NextButton_3 = uibutton(app.ModesTab, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next';
            app.PrevButton_3 = uibutton(app.ModesTab, 'push');
            app.PrevButton_3.ButtonPushedFcn = createCallbackFcn(app, @PrevButton_3Pushed, true);
            app.PrevButton_3.Text = 'Prev';
            app.FirstmodeEditFieldLabel = uilabel(app.ModesTab);
            app.FirstmodeEditFieldLabel.Text = 'First mode:';
            app.FirstmodeEditField = uieditfield(app.ModesTab, 'numeric');
            app.FirstmodeEditField.ValueChangedFcn = createCallbackFcn(app, @FirstmodeEditFieldValueChanged, true);
            app.LastmodeEditFieldLabel = uilabel(app.ModesTab);
            app.LastmodeEditFieldLabel.Text = 'Last mode:';
            app.LastmodeEditField = uieditfield(app.ModesTab, 'numeric');
            app.PlotmodesLabel = uilabel(app.ModesTab);
            app.PlotmodesLabel.Text = 'Plot modes';
            app.PlotButton = uibutton(app.ModesTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ClearButton = uibutton(app.ModesTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.UIAxes = uiaxes(app.ModesTab);
            title(app.UIAxes, 'FI Eigenvenctors')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DisplacementsTab = uitab(app.TabGroup);
            app.DisplacementsTab.Title = 'Displacements';
            app.NextButton_2 = uibutton(app.DisplacementsTab, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.PrevButton_2 = uibutton(app.DisplacementsTab, 'push');
            app.PrevButton_2.ButtonPushedFcn = createCallbackFcn(app, @PrevButton_2Pushed, true);
            app.PrevButton_2.Text = 'Prev';
            app.FirstmassEditFieldLabel = uilabel(app.DisplacementsTab);
            app.FirstmassEditFieldLabel.Text = 'First mass';
            app.FirstmassEditField = uieditfield(app.DisplacementsTab, 'numeric');
            app.FirstmassEditField.ValueChangedFcn = createCallbackFcn(app, @FirstmassEditFieldValueChanged, true);
            app.LastmassEditFieldLabel = uilabel(app.DisplacementsTab);
            app.LastmassEditFieldLabel.Text = 'Last mass';
            app.LastmassEditField = uieditfield(app.DisplacementsTab, 'numeric');
            app.PlotFRFLabel = uilabel(app.DisplacementsTab);
            app.PlotFRFLabel.Text = 'Plot FRF';
            app.PlotButton_2 = uibutton(app.DisplacementsTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.ClearButton_2 = uibutton(app.DisplacementsTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.UIAxes_2 = uiaxes(app.DisplacementsTab);
            title(app.UIAxes_2, 'Discplacements')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Moduselemzo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end